classdef issapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UTCEditField       matlab.ui.control.EditField
        UTCEditFieldLabel  matlab.ui.control.Label
        UTCSlider          matlab.ui.control.Slider
        GoButton           matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        utc % nx1 vector with utc data
        lla % nx3 vector with lat,long,altitude data
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            data = readdata();
            [app.utc,app.lla] = extractcoords(data);
            [smoothlat,smoothlong] = prepare2plot(app.lla);
            im = imread("world.200408.3x5400x2700.png");
            image(app.UIAxes,-180:180,-90:90,im);
            axis(app.UIAxes,"equal");
            axis(app.UIAxes,"off");
            hold(app.UIAxes,"on");
            plot(app.UIAxes,smoothlong,smoothlat);
            time = currenttime();
            app.UTCEditField.Value = time;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UTCSlider = uislider(app.UIFigure);
            app.UTCEditFieldLabel = uilabel(app.UIFigure);
            app.UTCEditFieldLabel.Text = 'UTC';
            app.UTCEditField = uieditfield(app.UIFigure, 'text');
            app.UTCEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = issapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end